classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TestDropDown           matlab.ui.control.DropDown
        TestDropDownLabel      matlab.ui.control.Label
        HoldCheckBox           matlab.ui.control.CheckBox
        LocationDropDown       matlab.ui.control.DropDown
        LocationDropDownLabel  matlab.ui.control.Label
        FanDropDown            matlab.ui.control.DropDown
        FanDropDownLabel       matlab.ui.control.Label
        ThemeDropDown          matlab.ui.control.DropDown
        ThemeDropDownLabel     matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        F01_air_velocity;   % exactly what it says it is
        F02_air_velocity;   % exactly what it says it is
        F03_air_velocity;   % exactly what it says it is
        F04_air_velocity;   % exactly what it says it is
        F05_air_velocity;   % exactly what it says it is
        F06_air_velocity;   % exactly what it says it is
        F07_air_velocity;   % exactly what it says it is
        F08_air_velocity;   % exactly what it says it is
        time_s;
        testLower;          % lower bound of test indices
        testUpper;          % upper bound of test indices
        test_index;         % this is only here so I don't have to waste time changing the start up function
        velFields;
        velFiles;
        color1;             % primary theme color
        color2;             % secondary theme color
    end
    
    methods (Access = private)
        
        function updateData(app)
            step = 35;

            locStr = app.LocationDropDown.Value;   % "L01".."L35"
            locNum = str2double(extractAfter(locStr, 'L')); % 1..35

            lower = app.testLower;
            upper = app.testUpper;

            for i = 1:numel(app.velFiles)
                S = load(app.velFiles{i});
                fn = fieldnames(S);
                T = S.(fn{1});

                % Candidate rows for this location within the test's upper bound
                idx = locNum:step:upper;

                % Enforce the test's lower bound (this is where location affects the effective start)
                idx = idx(idx >= lower);

                app.(app.velFields{i}) = reshape(T{idx, "air_speed_mps"}, 1, []);
                app.time_s             = reshape(T{idx, "time_sec"},     1, []);
            end
        end

        function updatePlot(app)
            if app.HoldCheckBox.Value
                hold(app.UIAxes,'on');   % keep existing plots
            else
                cla(app.UIAxes);        % clear only when hold is off
                hold(app.UIAxes,'off');
            end

            fanStr = app.FanDropDown.Value; 
            fanNum = str2double(extractAfter(fanStr, 'F')); 
            y = app.(app.velFields{fanNum});
            app.UIAxes.Title = text(app.UIAxes, 0, 0, 'Air Speed v.s. Time Elapsed', 'Color', app.color2);

            app.UIAxes.YLim = [0, (round(max(y), 3, 'decimals') + 0.1)];
            app.UIAxes.YTick = 0:(round((app.UIAxes.YLim(2)/4), 3, "decimals")):app.UIAxes.YLim(2);

            plot(app.UIAxes, app.time_s, y);
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % import files and graph the default graph when the app starts
            app.color1 = [0.13, 0.13, 0.13]; % very dark gray
            app.color2 = [0.96, 0.96, 0.96]; % very very very light gray
            app.velFields = {
                'F01_air_velocity', 'F02_air_velocity', ...
                'F03_air_velocity', 'F04_air_velocity', ...
                'F05_air_velocity', 'F06_air_velocity', ...
                'F07_air_velocity', 'F08_air_velocity'
            };

            app.velFiles = {
                'F01_air_velocity.mat', 'F02_air_velocity.mat', ...
                'F03_air_velocity.mat', 'F04_air_velocity.mat', ...
                'F05_air_velocity.mat', 'F06_air_velocity.mat', ...
                'F07_air_velocity.mat', 'F08_air_velocity.mat'
            };

            app.time_s = [];
            S = load(app.velFiles{1});

            % Assume the MAT file contains exactly one table variable:
            fn = fieldnames(S);
            T = S.(fn{1});  % <-- should be a table

            n = floor(height(T)/3);  % make sure it's an integer count
                
            app.(app.velFields{1}) = reshape(T{1:35:n, "air_speed_mps"}, 1, []);
            app.time_s = reshape(T{1:35:n, "time_sec"}, 1, []);

            app.UIAxes.XLim = [0, 1200];
            app.UIAxes.XTick = [0, 300, 600, 900, 1200];
            app.UIAxes.YLim = [0, (round(max(app.F01_air_velocity),3,'decimals') + 0.1)];
            app.UIAxes.YTick = 0:(round((app.UIAxes.YLim(2)/4),3,"decimals")):app.UIAxes.YLim(2);
            plot(app.UIAxes, app.time_s, app.F01_air_velocity);
        end

        % Value changed function: ThemeDropDown
        function ThemeDropDownValueChanged(app, event)
            app_theme = app.ThemeDropDown.Value;
            % actions to take based on different values
            switch app_theme
                case "Light"              % Light mode
                    app.color1 = [0.96, 0.96, 0.96]; % very very very light gray
                    app.color2 = [0, 0, 0];          % pitch black
                case "Dark"               % Dark mode
                    app.color1 = [0.13, 0.13, 0.13]; % very dark gray
                    app.color2 = [0.96, 0.96, 0.96]; % very very very light gray
                case "Midnight"           % 'Dark-er' mode
                    app.color1 = [0, 0, 0];          % pitch black
                    app.color2 = [0.5, 0.86, 0.86];  % light cyan (white is too bright, plus it looks cool)
            end
            % Defines attributes needing to = color1 when theme is changed
            color1_targets = {
                app.UIFigure,               'Color';
                app.ThemeDropDown,          'BackgroundColor';
                app.FanDropDown,            'BackgroundColor';
                app.LocationDropDown,       'BackgroundColor';
                app.TestDropDown,           'BackgroundColor';
                app.UIAxes,                 'Color'
            };

            % Defines attributes needing to = color2 when theme is changed
            color2_targets = {
                app.ThemeDropDown,          'FontColor';
                app.ThemeDropDownLabel,     'FontColor';
                app.LocationDropDown,       'FontColor';
                app.LocationDropDownLabel,  'FontColor';
                app.FanDropDown,            'FontColor';
                app.FanDropDownLabel,       'FontColor';
                app.TestDropDown,           'FontColor';
                app.TestDropDownLabel,      'FontColor';
                app.UIAxes,                 'GridColor';
                app.UIAxes,                 'MinorGridColor';
                app.UIAxes,                 'XColor';
                app.UIAxes,                 'YColor';
                app.UIAxes,                 'AmbientLightColor';
                app.HoldCheckBox,           'FontColor'
            };

            % Each attribute in color1_targets is changed to color1
            for i = 1:size(color1_targets,1)
                set(color1_targets{i,1}, color1_targets{i,2}, app.color1);
            end

            % Each attribute in color2_targets is changed to color2
            for i = 1:size(color2_targets,1)
                set(color2_targets{i,1}, color2_targets{i,2}, app.color2);
            end
            app.updatePlot();
        end

        % Value changed function: FanDropDown
        function FanDropDownValueChanged(app, event)
            app.updatePlot();            
        end

        % Value changed function: HoldCheckBox
        function HoldCheckBoxValueChanged(app, event)
            Hold_on = app.HoldCheckBox.Value;
            switch Hold_on
                case 1
                    hold(app.UIAxes, 'on')
                case 0
                    hold(app.UIAxes, 'off')
            end            
        end

        % Value changed function: LocationDropDown
        function LocationDropDownValueChanged(app, event)
            app.updateData();
            app.updatePlot();
        end

        % Value changed function: TestDropDown
        function TestDropDownValueChanged(app, event)
            S = load(app.velFiles{1});
            fn = fieldnames(S);
            T = S.(fn{1});
            H = height(T);

            b1 = floor(H/3);
            b2 = floor(2*H/3);

            switch app.TestDropDown.Value
                case 'Test 1'
                    app.testLower = 1;
                    app.testUpper = b1;
                case 'Test 2'
                    app.testLower = b1 + 1;
                    app.testUpper = b2;
                case 'Test 3'
                    app.testLower = b2 + 1;
                    app.testUpper = H;
            end

            app.updateData();
            app.updatePlot();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Air Speed v.s. Time Elapsed')
            xlabel(app.UIAxes, 'Time Elapsed (min)')
            ylabel(app.UIAxes, 'Air Speed (mps)')
            app.UIAxes.XLim = [0 1200];
            app.UIAxes.YLim = [0 0.5];
            app.UIAxes.XTick = [0 300 600 900 1200];
            app.UIAxes.XTickLabel = {'0'; '5'; '10'; '15'; '20'};
            app.UIAxes.YTick = [0 0.125 0.25 0.375 0.5];
            app.UIAxes.Toolbar = [];
            app.ThemeDropDownLabel = uilabel(app.UIFigure);
            app.ThemeDropDownLabel.Text = 'Theme';
            app.ThemeDropDown = uidropdown(app.UIFigure);
            app.ThemeDropDown.Items = {'Light', 'Dark', 'Midnight'};
            app.ThemeDropDown.ValueChangedFcn = createCallbackFcn(app, @ThemeDropDownValueChanged, true);
            app.ThemeDropDown.Tooltip = {'Switch between different light-level based themes'};
            app.ThemeDropDown.Placeholder = 'Theme';
            app.ThemeDropDown.Value = 'Dark';
            app.FanDropDownLabel = uilabel(app.UIFigure);
            app.FanDropDownLabel.Text = 'Fan';
            app.FanDropDown = uidropdown(app.UIFigure);
            app.FanDropDown.Items = {'F01', 'F02', 'F03', 'F04', 'F05', 'F06', 'F07', 'F08'};
            app.FanDropDown.ValueChangedFcn = createCallbackFcn(app, @FanDropDownValueChanged, true);
            app.FanDropDown.Tooltip = {'Switch between fans. If hold is checked, selecting another fan will plot it on top of the current graph.'};
            app.FanDropDown.Placeholder = 'Theme';
            app.FanDropDown.Value = 'F01';
            app.LocationDropDownLabel = uilabel(app.UIFigure);
            app.LocationDropDownLabel.Text = 'Location';
            app.LocationDropDown = uidropdown(app.UIFigure);
            app.LocationDropDown.Items = {'L01', 'L02', 'L03', 'L04', 'L05', 'L06', 'L07', 'L08', 'L09', 'L10', 'L11', 'L12', 'L13', 'L14', 'L15', 'L16', 'L17', 'L18', 'L19', 'L20', 'L21', 'L22', 'L23', 'L24', 'L25', 'L26', 'L27', 'L28', 'L29', 'L30', 'L31', 'L32', 'L33', 'L34', 'L35'};
            app.LocationDropDown.ValueChangedFcn = createCallbackFcn(app, @LocationDropDownValueChanged, true);
            app.LocationDropDown.Tooltip = {'Switch between sensor locations. If hold is checked, selecting another location will plot it on top of the current graph.'};
            app.LocationDropDown.Placeholder = 'Theme';
            app.LocationDropDown.Value = 'L01';
            app.HoldCheckBox = uicheckbox(app.UIFigure);
            app.HoldCheckBox.ValueChangedFcn = createCallbackFcn(app, @HoldCheckBoxValueChanged, true);
            app.HoldCheckBox.Tooltip = {'Allows plotting of multiple fans/variables'};
            app.HoldCheckBox.Text = 'Hold';
            app.TestDropDownLabel = uilabel(app.UIFigure);
            app.TestDropDownLabel.Text = 'Test';
            app.TestDropDown = uidropdown(app.UIFigure);
            app.TestDropDown.Items = {'Test 1', 'Test 2', 'Test 3'};
            app.TestDropDown.ValueChangedFcn = createCallbackFcn(app, @TestDropDownValueChanged, true);
            app.TestDropDown.Tooltip = {'Switch between test runs. If hold is checked, selecting another test will plot it on top of the current graph.'};
            app.TestDropDown.Placeholder = 'Theme';
            app.TestDropDown.Value = 'Test 1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end