classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        FanDropDown         matlab.ui.control.DropDown
        FanDropDownLabel    matlab.ui.control.Label
        settingsButton      matlab.ui.control.Button
        ThemeDropDown       matlab.ui.control.DropDown
        ThemeDropDownLabel  matlab.ui.control.Label
        UIAxes              matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % import files when the app starts
            F101_data1 = [0.00, 0.60, 0.20, 0.25, 0.50, 0.70, 0.60, 0.55, 0.70, 0.80, 0.80, 0.70];
            x          = linspace(0, 24, 12);
            plot(app.UIAxes, x, F101_data1)
        end

        % Value changed function: ThemeDropDown
        function ThemeDropDownValueChanged(app, event)
            app_theme = app.ThemeDropDown.Value;
            % actions to take based on different values
            switch app_theme
                case "Light"              % Light mode
                    color1 = [0.96, 0.96, 0.96]; % very very very light gray
                    color2 = [0, 0, 0];          % pitch black
                case "Dark"               % Dark mode
                    color1 = [0.13, 0.13, 0.13]; % very dark gray
                    color2 = [0.96, 0.96, 0.96]; % very very very light gray
                case "Midnight"           % 'Dark-er' mode
                    color1 = [0, 0, 0];          % pitch black
                    color2 = [0.2, 0.86, 0.86];  % light cyan (white is too bright, plus it looks cool)
            end
            % Defines attributes needing to = color1 when theme is changed
            color1_targets = {
                app.UIFigure,               'Color';
                app.ThemeDropDown,          'BackgroundColor';
                app.FanDropDown,            'BackgroundColor';
                app.UIAxes,                 'Color'};

            % Defines attributes needing to = color2 when theme is changed
            color2_targets = {
                app.ThemeDropDown,          'FontColor';
                app.FanDropDown,            'FontColor';
                app.FanDropDownLabel,       'FontColor';
                app.ThemeDropDownLabel,     'FontColor';
                app.UIAxes,                 'GridColor';
                app.UIAxes,                 'MinorGridColor';
                app.UIAxes,                 'XColor';
                app.UIAxes,                 'YColor';
                app.UIAxes,                 'AmbientLightColor'};

            % Each attribute in color1_targets is changed to color1
            for i = 1:size(color1_targets,1)
                set(color1_targets{i,1}, color1_targets{i,2}, color1);
            end

            % Each attribute in color2_targets is changed to color2
            for i = 1:size(color2_targets,1)
                set(color2_targets{i,1}, color2_targets{i,2}, color2);
            end
        end

        % Button pushed function: settingsButton
        function settingsButtonPushed(app, event)

        end

        % Value changed function: FanDropDown
        function FanDropDownValueChanged(app, event)
            fan_dropdown_fan_id = app.FanDropDown.Value;
            data = load('fan_air_velocity.mat');
            vars = fieldnames(data);
            s = data.(vars{1});          % use the first variable stored in the MAT-file
            T = struct2table(s);
            F01_data1 = [0.00, 0.60, 0.20, 0.25, 0.50, 0.70, 0.60, 0.55, 0.70, 0.80, 0.80, 0.70];
            F02_data1 = [0.42, 0.98, 0.22, 0.10, 0.01, 0.90, 0.92, 0.19, 0.58, 0.79, 0.60, 0.43];
            F03_data1 = [0.99, 0.59, 0.57, 0.50, 0.96, 0.78, 0.72, 0.43, 0.89, 0.79, 0.49, 0.46];
            F04_data1 = [0.87, 0.70, 0.89, 0.19, 0.65, 0.65, 0.60, 0.81, 0.22, 0.51, 0.42, 0.76];
            F05_data1 = [0.67, 0.29, 0.08, 0.43, 0.60, 0.22, 0.91, 0.10, 0.28, 0.50, 0.00, 0.61];
            F06_data1 = [0.46, 0.19, 0.65, 0.96, 0.56, 0.77, 0.76, 0.75, 0.55, 0.70, 0.62, 0.64];
            F07_data1 = [0.42, 0.49, 0.95, 0.30, 0.12, 0.64, 0.86, 0.56, 0.09, 0.03, 0.78, 0.27];
            F08_data1 = [0.18, 0.29, 0.57, 0.60, 0.37, 0.69, 0.14, 0.37, 0.31, 0.15, 0.20, 0.86];
            x          = linspace(8, 20, 12);
            switch fan_dropdown_fan_id
                case 'F01'
                    plot(app.UIAxes, x, F01_data1)
                case 'F02'
                    plot(app.UIAxes, x, F02_data1)
                case 'F03'
                    plot(app.UIAxes, x, F03_data1)
                case 'F04'
                    plot(app.UIAxes, x, F04_data1)
                case 'F05'
                    plot(app.UIAxes, x, F05_data1)
                case 'F06'
                    plot(app.UIAxes, x, F06_data1)
                case 'F07'
                    plot(app.UIAxes, x, F07_data1)
                case 'F08'
                    plot(app.UIAxes, x, F08_data1)
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'v.s. Time')
            xlabel(app.UIAxes, 'Time (24h)')
            app.UIAxes.XLim = [0 24];
            app.UIAxes.YLimMode = 'manual';
            app.UIAxes.XTick = [0 6 12 18 24];
            app.UIAxes.XTickLabel = {'0h'; '6h'; '12h'; '18h'; '24h'};
            app.UIAxes.Toolbar = [];
            app.ThemeDropDownLabel = uilabel(app.UIFigure);
            app.ThemeDropDownLabel.Text = 'Theme';
            app.ThemeDropDown = uidropdown(app.UIFigure);
            app.ThemeDropDown.Items = {'Light', 'Dark', 'Midnight'};
            app.ThemeDropDown.ValueChangedFcn = createCallbackFcn(app, @ThemeDropDownValueChanged, true);
            app.ThemeDropDown.Placeholder = 'Theme';
            app.ThemeDropDown.Value = 'Dark';
            app.settingsButton = uibutton(app.UIFigure, 'push');
            app.settingsButton.ButtonPushedFcn = createCallbackFcn(app, @settingsButtonPushed, true);
            app.settingsButton.Icon = fullfile(pathToMLAPP, 'vector-gear-settings-icon-11642664102rwrsyjsiwu-1184377603 (1).png');
            app.settingsButton.Text = '';
            app.FanDropDownLabel = uilabel(app.UIFigure);
            app.FanDropDownLabel.Text = 'Fan';
            app.FanDropDown = uidropdown(app.UIFigure);
            app.FanDropDown.Items = {'F101', 'F102', 'F103', 'F104', 'F105', 'F106', 'F107', 'F108'};
            app.FanDropDown.ValueChangedFcn = createCallbackFcn(app, @FanDropDownValueChanged, true);
            app.FanDropDown.Placeholder = 'Theme';
            app.FanDropDown.Value = 'F101';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end